classdef RoutePlannerV0_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        MainTab                     matlab.ui.container.Tab
        Image2                      matlab.ui.control.Image
        Image3                      matlab.ui.control.Image
        TravelsTab                  matlab.ui.container.Tab
        FleettravelsPanel           matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        mileagePlot                 matlab.ui.control.Button
        VehicleListBoxLabel         matlab.ui.control.Label
        vehicleSelectionMillage     matlab.ui.control.ListBox
        TypeofplotDropDownLabel     matlab.ui.control.Label
        typeofPlot                  matlab.ui.control.DropDown
        BinsEditFieldLabel          matlab.ui.control.Label
        BinsEditField               matlab.ui.control.NumericEditField
        LoaddataButton              matlab.ui.control.Button
        daysSelection               matlab.ui.container.ButtonGroup
        weekdays_1                  matlab.ui.control.RadioButton
        weekends_1                  matlab.ui.control.RadioButton
        week_1                      matlab.ui.control.RadioButton
        YearSpinnerLabel            matlab.ui.control.Label
        Year                        matlab.ui.control.Spinner
        MonthSpinnerLabel           matlab.ui.control.Label
        Month                       matlab.ui.control.Spinner
        Alldataset                  matlab.ui.control.CheckBox
        EvaluationTab               matlab.ui.container.Tab
        CalculateN2OButton          matlab.ui.control.Button
        CalculateCH4Button          matlab.ui.control.Button
        StatisticsPanel             matlab.ui.container.Panel
        StatisticsButtonGroup       matlab.ui.container.ButtonGroup
        AverageDailyDriving         matlab.ui.control.RadioButton
        ProbabilityDistribution     matlab.ui.control.RadioButton
        Boxplot                     matlab.ui.control.RadioButton
        BinsEditField_2Label        matlab.ui.control.Label
        Bins2                       matlab.ui.control.NumericEditField
        daysSelection_2             matlab.ui.container.ButtonGroup
        weekdays_2                  matlab.ui.control.RadioButton
        weekends_2                  matlab.ui.control.RadioButton
        week_2                      matlab.ui.control.RadioButton
        VehicleListBoxLabel_2       matlab.ui.control.Label
        vehicleSelectionGHG         matlab.ui.control.ListBox
        Plot1_GHG                   matlab.ui.control.Button
        UIAxesEval                  matlab.ui.control.UIAxes
        GreenHouseGasEmissionsCO2CH2N2OHFCCO2ePanel  matlab.ui.container.Panel
        ConsumptionListBoxLabel     matlab.ui.control.Label
        Consumption                 matlab.ui.control.ListBox
        ICEListBoxLabel             matlab.ui.control.Label
        ICEListBox                  matlab.ui.control.ListBox
        CalculateGHGButton          matlab.ui.control.Button
        EVListBoxLabel              matlab.ui.control.Label
        EVListBox                   matlab.ui.control.ListBox
        UIAxesGHG                   matlab.ui.control.UIAxes
        FuelButtonGroup             matlab.ui.container.ButtonGroup
        PetrolButton                matlab.ui.control.RadioButton
        DieselButton                matlab.ui.control.RadioButton
        E10Button                   matlab.ui.control.RadioButton
        ElectricityButtonGroup      matlab.ui.container.ButtonGroup
        TurkuEnButton               matlab.ui.control.RadioButton
        HelenButton                 matlab.ui.control.RadioButton
        RoutePlannerTab             matlab.ui.container.Tab
        createaNewTrip              matlab.ui.control.Button
        loadaRouteButton            matlab.ui.control.Button
        DaysListBoxLabel            matlab.ui.control.Label
        RouteListBox                matlab.ui.control.ListBox
        plotRoute                   matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        ExportCSVButton             matlab.ui.control.Button
        RoutePanel                  matlab.ui.container.Panel
        SatellitepictureCheckBox    matlab.ui.control.CheckBox
        SettingsTab                 matlab.ui.container.Tab
        CO2EmissionsgrCO2LgrCO2kWhPanel  matlab.ui.container.Panel
        PetrolEditFieldLabel        matlab.ui.control.Label
        PetrolEditField             matlab.ui.control.NumericEditField
        DieselEditFieldLabel        matlab.ui.control.Label
        DieselEditField             matlab.ui.control.NumericEditField
        HelenEditFieldLabel         matlab.ui.control.Label
        HelenEditField              matlab.ui.control.NumericEditField
        TurkuEnergiaEditFieldLabel  matlab.ui.control.Label
        TurkuEnergiaEditField       matlab.ui.control.NumericEditField
        COemissionstoproduce1kWhofelectricityLabel  matlab.ui.control.Label
        E10EditFieldLabel           matlab.ui.control.Label
        E10EditField                matlab.ui.control.NumericEditField
        GlobalWarmingPotentialCO2ePanel  matlab.ui.container.Panel
        MethaneEditFieldLabel       matlab.ui.control.Label
        MethaneEditField            matlab.ui.control.NumericEditField
        NitrousOxideEditFieldLabel  matlab.ui.control.Label
        NitrousOxideEditField       matlab.ui.control.NumericEditField
        ACEditFieldLabel            matlab.ui.control.Label
        ACEditField                 matlab.ui.control.NumericEditField
        ICEFuelConsumptionliters100KmPanel  matlab.ui.container.Panel
        CityEditFieldLabel          matlab.ui.control.Label
        cityICE                     matlab.ui.control.NumericEditField
        HighwayEditFieldLabel       matlab.ui.control.Label
        highwayICE                  matlab.ui.control.NumericEditField
        CombinedEditFieldLabel      matlab.ui.control.Label
        combinedICE                 matlab.ui.control.NumericEditField
        DrivingbehaviourPanel       matlab.ui.container.Panel
        WeekdayEditFieldLabel       matlab.ui.control.Label
        WeekdayEditField            matlab.ui.control.NumericEditField
        WeekendEditFieldLabel       matlab.ui.control.Label
        WeekendEditField            matlab.ui.control.NumericEditField
        HolidaysEditFieldLabel      matlab.ui.control.Label
        HolidaysEditField           matlab.ui.control.NumericEditField
        CombinedEditField_4Label    matlab.ui.control.Label
        CombinedEditField_4         matlab.ui.control.NumericEditField
        EVEnergyConsumptionkWh100KmPanel  matlab.ui.container.Panel
        CityEditField_2Label        matlab.ui.control.Label
        cityEV                      matlab.ui.control.NumericEditField
        HighwayEditField_2Label     matlab.ui.control.Label
        highwayEV                   matlab.ui.control.NumericEditField
        CombinedEditField_5Label    matlab.ui.control.Label
        combinedEV                  matlab.ui.control.NumericEditField
        terminal                    matlab.ui.control.EditField
        terminalLabel               matlab.ui.control.Label
    end


    properties (Access = public)
        T         % Original table
        weekdaysT % Weekdays table
        weekendsT % Weekends table
        date      % Date column
        dataT     % Vehicles columns
        data      % Vehicles array
        dateTime      
        route     % Structure with the planned routes
        filter_trip_name
        routeT    % Route table
        file
        fileName
        errorVehicle
        previousLayerName
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Assign prevalues for the route table 
            app.routeT = readtable('routes.csv');
            app.UITable.Data = app.routeT;
            set(groot,'defaultGeoaxesBasemap','streets-light')
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Read a millage file
            %
            % Format. An excel or csv file. [datetime, mileageCar1, mileagecar2 .....]
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            try
                travelfile = uigetfile({'*.csv', 'csv (*.csv)'});
                app.T = readtable(travelfile);
                app.date = app.T.Date;
                [app.dateTime.year, app.dateTime.month, app.dateTime.day, app.dateTime.hour, app.dateTime.minute, app.dateTime.second] = datevec(app.date);
                weekend = isweekend(app.date);
                app.weekdaysT = app.T(weekend == 0, :);
                app.weekendsT = app.T(weekend == 1, :);
                app.dataT = app.T(:, [2:25]);
                app.data = table2array(app.dataT);
                app.vehicleSelectionMillage.Items = app.T.Properties.VariableNames(2:end);
                app.vehicleSelectionGHG.Items = app.T.Properties.VariableNames(2:end);
                % Add a message ()
                
            catch ME
                % Add a message here (File not found or file does not have the required format)
                if (strcmp(ME.identifier, 'MATLAB:readtable:OpenFailed'))
                    app.terminal.Value = "Error: File not found";
                end
                
                if (strcmp(ME.identifier, 'MATLAB:UndefinedFunction'))
                    app.terminal.Value = "Error: Data incorrectly formatted. Is date in the beginning?";
                end
            end
            
        end

        % Value changed function: typeofPlot
        function Typeofplot_Millage(app, event)
            value = app.typeofPlot.Value;
            if strcmp(value , 'Histogram') ==1
                app.BinsEditFieldLabel.Enable = 'on';
                app.BinsEditField.Enable = 'on';
            elseif strcmp(value , 'Daily') ==1
                app.BinsEditFieldLabel.Enable = 'off';
                app.BinsEditField.Enable = 'off';
            else
                app.BinsEditFieldLabel.Enable = 'off';
                app.BinsEditField.Enable = 'off';
            end
        end

        % Button pushed function: mileagePlot
        function mileagePlot_button(app, event)
            try
                year = app.Year.Value;
                month = app.Month.Value;
                app.Alldataset.Value;
                
                filter = and(app.dateTime.month == month, app.dateTime.year == year);
                
                if app.Alldataset.Value == 0
                    table0 = app.T(filter, :);
                    weekend = isweekend(table0.Date);
                else
                    table0 = app.T;
                    weekend = isweekend(table0.Date);
                end
                
                if app.weekdays_1.Value == 1
                    table1 = table0(weekend == 0, :);
                elseif app.weekends_1.Value == 1
                    table1 = table0(weekend == 1, :);
                elseif app.week_1.Value == 1
                    table1 = table0;
                end
                
                plotType = app.typeofPlot.Value;
                vehicle = app.vehicleSelectionMillage.Value;
                app.errorVehicle = vehicle;
                
                try
                    
                    distance = table1{:,vehicle};
                    
                    if strcmp(plotType , 'Daily') == 1
                        bar(app.UIAxes, table1.Date, distance)
                        xlabel(app.UIAxes,'Date')
                        ylabel(app.UIAxes,'Distance (Km)')
                        legend(app.UIAxes, vehicle, 'Location','northeastoutside') %
                    elseif strcmp(plotType , 'Cumulative') == 1
                        cum_distance = cumsum(distance);
                        plot(app.UIAxes, table1.Date, cum_distance)
                        xlabel(app.UIAxes,'Date')
                        ylabel(app.UIAxes,'Distance (Km)')
                        legend(app.UIAxes, vehicle, 'Location','northeastoutside') %
                    elseif strcmp(plotType , 'Histogram') == 1
                        bin = app.BinsEditField.Value;
                        h = histogram(app.UIAxes, distance, bin);
                        h.Data
                        h.Values
                        xlabel(app.UIAxes,'Distance (Km)')
                        ylabel(app.UIAxes,'Frequency')
                        
                        app.terminal.Value = "Successfully plotted vehicle mileage.";
                    end
                    
                catch
                    app.terminal.Value = "Error: No vehicle has been chosen.";
                end
                
            catch
                app.terminal.Value = "Error: No data has been imported, or the file is formatted wrong.";
            end
        end

        % Button pushed function: Plot1_GHG
        function Plot1_Evaluation(app, event)
            vehicle = app.vehicleSelectionGHG.Value;
            
            %Filter the data selected days
            if app.weekdays_2.Value == 1
                table = app.weekdaysT;
            elseif app.weekends_2.Value == 1
                table = app.weekendsT;
            elseif app.week_2.Value == 1
                table = app.dataT;
            end
            
            try
                distance = table{:,vehicle};
                
                % Plot based on selection
                cla(app.UIAxesEval, "reset");
                
                try
                    if app.AverageDailyDriving.Value == 1
                        
                        
                        avg = mean(table{:, vehicle}, 'all');
                        names=categorical(vehicle);
                        bar(app.UIAxesEval,names, mean(table{:, vehicle}));
                        ylabel(app.UIAxesEval,'Distance (Km)')
                        
                        try
                            
                            yline(app.UIAxesEval, avg, 'g', 'LineWidth', 2,'label', 'Mean');
                            
                        catch
                            app.terminal.Value = "Error: No vehicle selected for plotting. (Average)";
                        end
                        
                    elseif app.ProbabilityDistribution.Value == 1
                        
                        try
                            
                            bin = app.Bins2.Value;
                            h2 = histogram(app.UIAxesEval, distance, bin, 'Normalization', "probability");
                            ylabel(app.UIAxesEval,'Probability')
                            xlabel(app.UIAxesEval,'Distance (Km)')
                            
                        catch
                            app.terminal.Value = "Error: No vehicle selected for plotting. (Probability)";
                        end
                        
                    elseif app.Boxplot.Value == 1
                        
                        try
                            
                            boxplot(app.UIAxesEval, table{:, vehicle}, vehicle);
                            ylabel(app.UIAxesEval,'Distance (Km)')
                            meanSelectedVehicles = mean(table{:, vehicle}, 'all');
                            sdSelectedVehicles = std(table{:, vehicle}, 0,[1 2]);
                            
                            try
                                yline(app.UIAxesEval, meanSelectedVehicles, 'g', 'LineWidth', 2, 'label', 'Mean');
                                yline(app.UIAxesEval, sdSelectedVehicles, 'c', 'LineWidth', 2, 'label','SD' );
                                
                            catch
                                app.terminal.Value = "Error: No vehicle selected for plotting. (BoxPlot)";
                            end
                            
                            
                        catch
                            app.terminal.Value = "Error: No vehicle selected for plotting. (BoxPlot)";
                        end
                        
                    end
                    
                catch
                    app.terminal.Value = "Error: Somehow a nonavailable plot type was chosen.";
                end
                
            catch
                app.terminal.Value = "Error: No data loaded.";
            end
            
        end

        % Button pushed function: CalculateGHGButton
        function calculateGHG(app, event)
            if app.PetrolButton.Value == 1
                fuelConsmp = app.PetrolEditField.Value;
            elseif app.DieselButton.Value == 1
                fuelConsmp = app.DieselEditField.Value;
            elseif app.E10Button.Value == 1
                fuelConsmp = app.E10EditField.Value;
            end   
            
            if app.TurkuEnButton.Value == 1
                kwhConsmp = app.TurkuEnergiaEditField.Value;
            elseif app.HelenButton.Value == 1
                kwhConsmp = app.HelenEditField.Value;
            end
            
            ice = app.ICEListBox.Value;
            ev = app.EVListBox.Value;
            consump_sel = app.Consumption.Value;
            
            if app.weekdays_2.Value == 1
                table = app.weekdaysT;
            elseif app.weekends_2.Value == 1
                table = app.weekendsT;
            elseif app.week_2.Value == 1
                table = app.T;
            end
            
            try
                cumT_ice = cumsum(table{:,ice});
                cumT_ev = cumsum(table{:,ev});
                
                if strcmp(consump_sel, 'City') == 1
                    consumptionICE = app.cityICE.Value;
                    consumptionEV = app.cityEV.Value;
                elseif strcmp(consump_sel, 'Highway') == 1
                    consumptionICE = app.highwayICE.Value;
                    consumptionEV = app.highwayEV.Value;
                elseif strcmp(consump_sel, 'Combined') == 1
                    consumptionICE = app.combinedICE.Value;
                    consumptionEV = app.combinedEV.Value;
                end
                
                co2_ice = cumT_ice*((fuelConsmp*(consumptionICE/100))/1000000);
                co2_ev = cumT_ev*((kwhConsmp*(consumptionEV/100))/1000000);

                plot(app.UIAxesGHG, table.Date, co2_ice, table.Date, co2_ev);
                legend(app.UIAxesGHG, [ice ev],'Location','northeastoutside')
            catch
                app.terminal.Value = "Error: No data loaded. (GHG)";
            end
        end

        % Button pushed function: createaNewTrip
        function createaNewTripPushed(app, event)
            url = 'https://www.google.com/maps/d/u/0/';
            web(url)
        end

        % Button pushed function: loadaRouteButton
        function loadaRouteButtonPushed(app, event)
            try
                [app.route, app.file, app.fileName] = load_file();
            catch
                app.terminal.Value = 'Error: File window closed without choosing a file.';
            end
            
            try
                if (contains(app.file, '.gpx'))
                    try
                        app.filter_trip_name = app.route(strcmp({app.route.Geometry}, 'Point'));
                    catch
                        app.terminal.Value = 'Error: Trip name filtering failed';
                    end
                    
                    %try
                    app.RouteListBox.Items = {app.filter_trip_name.Metadata.Name};
                    %catch
                    % app.RouteListBox.Items = {app.filter};
                    %end
                    
                else
                    try
                        app.filter_trip_name = app.route(strcmp({app.route.Geometry}, 'Line'));
                    catch
                        app.terminal.Value = 'Error: Trip name filtering failed';
                    end
                    
                    try
                        app.RouteListBox.Items = {app.filter_trip_name.Name};
                    catch
                        app.terminal.Value = "Error : Couldn't add trips to list.";
                    end
                end
            catch
                app.terminal.Value = 'Error: No file loaded.';
            end
        end

        % Button pushed function: plotRoute
        function plotRoutePushed(app, event)
            % Make shorter. Function file or something else.
            
            try
                if (contains(app.file, '.kml'))
                    
                    selection = app.RouteListBox.Value;
                    field_element = find(strcmp({app.filter_trip_name.Name},selection)==1);
                    lat = app.filter_trip_name(field_element).Lat;
                    lon = app.filter_trip_name(field_element).Lon;
                    [rows, ~] = size(lat);
                    
                    arclen = [];
                    
                    for i= 1:1:(rows-1)
                        [arclen(i),~] = distance(lat(i), lon(i), lat(i+1), lon(i+1));
                    end
                    
                elseif (contains(app.file, '.gpx'))
                    
                    selection = app.RouteListBox.Value;
                    lat = app.route.Latitude;
                    lon = app.route.Longitude;
                    [~,columns] = size(lat);
                    arclen = [];
                    
                    for i= 1:1:(columns-1)
                        [arclen(i),~] = distance(lat(i), lon(i), lat(i+1), lon(i+1));
                    end
                    
                end
                
                
                dist = deg2km(arclen);
                tot_dist = sum(dist);
                t = now;
                di = datetime(t,'ConvertFrom','datenum');
                % Get the date when the file was created
                d = System.IO.File.GetCreationTime(app.file);
                creationDateTime = datetime(d.Year, d.Month, d.Day, d.Hour, d.Minute, d.Second);
                route.distance= tot_dist;
                route.layerName = selection;
                route.consumption = (app.cityEV.Value/100)*route.distance;
                %app.UITable.Properties.VariableNames
                
                % dataFields = {'Date', 'Map', 'Layer', 'Distance', 'Consumption'}
                % data = [creationDateTime, app.fileName, selection, route.distance, route.consumption];
                
                
                t1 = app.UITable.Data;
                [rows, ~] = size(t1);
                t1(rows,:) = t1(rows, :);
                
                if (app.UITable.Data.('Distance') == 0)
                    app.UITable.Data.('Date')(rows) = creationDateTime;
                    %a = app.filter_trip_name(field_element).Name
                    app.UITable.Data.('Map')(rows) = {app.fileName};
                    app.UITable.Data.('Layer')(rows) = {selection};%= {'Tuesday'}
                    app.UITable.Data.('Distance')(rows) = round(route.distance, 4);
                    app.previousLayerName = route.distance;
                    app.UITable.Data.('Consumption')(rows) = round(route.consumption, 4);
                    
                elseif (app.previousLayerName == route.distance)
                    
                else
                    app.UITable.Data.('Date')(rows+1) = creationDateTime;
                    %a = app.filter_trip_name(field_element).Name
                    app.UITable.Data.('Map')(rows+1) = {app.fileName};
                    app.UITable.Data.('Layer')(rows+1) = {selection};%= {'Tuesday'}
                    app.UITable.Data.('Distance')(rows+1) = round(route.distance, 4);
                    app.previousLayerName = route.distance;
                    app.UITable.Data.('Consumption')(rows+1) = round(route.consumption, 4);
                end


                app.RoutePanel.clo;
                gx = geoaxes(app.RoutePanel);
                
                if (app.SatellitepictureCheckBox.Value == 1)
                    geoplot(gx,lat,lon, 'LineWidth',3)
                else
                    geoplot(gx,lat,lon, 'LineWidth',2)
                end
                
            catch
                app.terminal.Value = 'Error: File was not .kml or .gpx';
            end
        end

        % Button pushed function: ExportCSVButton
        function ExportCSVButtonPushed(app, event)
            try
                writetable(app.UITable.Data, "ExportedRouteData.csv")
                app.terminal.Value = 'Table exported successfully';
            catch
                app.terminal.Value = 'Error: File already open';
            end
        end

        % Value changed function: SatellitepictureCheckBox
        function SatellitepictureCheckBoxValueChanged(app, event)
            value = app.SatellitepictureCheckBox.Value;
            if (value == 1) 
                set(groot,'defaultGeoaxesBasemap','satellite')
            else 
                set(groot,'defaultGeoaxesBasemap','streets-light')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.Image2 = uiimage(app.MainTab);
            app.Image2.ImageSource = 'wholemap.png';
            app.Image3 = uiimage(app.MainTab);
            app.Image3.ImageSource = 'bsr-logo.png';
            app.TravelsTab = uitab(app.TabGroup);
            app.TravelsTab.Title = 'Travels';
            app.FleettravelsPanel = uipanel(app.TravelsTab);
            app.FleettravelsPanel.Title = 'Fleet travels';
            app.UIAxes = uiaxes(app.FleettravelsPanel);
            title(app.UIAxes, 'Distance traveled')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Kilometers (Km)')
            app.mileagePlot = uibutton(app.FleettravelsPanel, 'push');
            app.mileagePlot.ButtonPushedFcn = createCallbackFcn(app, @mileagePlot_button, true);
            app.mileagePlot.Text = 'Plot';
            app.VehicleListBoxLabel = uilabel(app.FleettravelsPanel);
            app.VehicleListBoxLabel.Text = 'Vehicle';
            app.vehicleSelectionMillage = uilistbox(app.FleettravelsPanel);
            app.vehicleSelectionMillage.Items = {};
            app.vehicleSelectionMillage.Multiselect = 'on';
            app.vehicleSelectionMillage.Value = {};
            app.TypeofplotDropDownLabel = uilabel(app.FleettravelsPanel);
            app.TypeofplotDropDownLabel.Text = 'Type of plot';
            app.typeofPlot = uidropdown(app.FleettravelsPanel);
            app.typeofPlot.Items = {'Daily', 'Cumulative', 'Histogram'};
            app.typeofPlot.ValueChangedFcn = createCallbackFcn(app, @Typeofplot_Millage, true);
            app.typeofPlot.Value = 'Daily';
            app.BinsEditFieldLabel = uilabel(app.FleettravelsPanel);
            app.BinsEditFieldLabel.Enable = 'off';
            app.BinsEditFieldLabel.Text = 'Bins';
            app.BinsEditField = uieditfield(app.FleettravelsPanel, 'numeric');
            app.BinsEditField.Enable = 'off';
            app.BinsEditField.Value = 11;
            app.LoaddataButton = uibutton(app.FleettravelsPanel, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.daysSelection = uibuttongroup(app.FleettravelsPanel);
            app.daysSelection.Title = 'Day';
            app.weekdays_1 = uiradiobutton(app.daysSelection);
            app.weekdays_1.Text = 'Weekdays';
            app.weekdays_1.Value = true;
            app.weekends_1 = uiradiobutton(app.daysSelection);
            app.weekends_1.Text = 'Weekends';
            app.week_1 = uiradiobutton(app.daysSelection);
            app.week_1.Text = 'Week';
            app.YearSpinnerLabel = uilabel(app.FleettravelsPanel);
            app.YearSpinnerLabel.Text = 'Year';
            app.Year = uispinner(app.FleettravelsPanel);
            app.Year.Limits = [2018 2020];
            app.Year.Value = 2019;
            app.MonthSpinnerLabel = uilabel(app.FleettravelsPanel);
            app.MonthSpinnerLabel.Text = 'Month';
            app.Month = uispinner(app.FleettravelsPanel);
            app.Month.Limits = [1 12];
            app.Month.Value = 5;
            app.Alldataset = uicheckbox(app.FleettravelsPanel);
            app.Alldataset.Text = ' All dataset';
            app.EvaluationTab = uitab(app.TabGroup);
            app.EvaluationTab.Title = 'Evaluation';
            app.CalculateN2OButton = uibutton(app.EvaluationTab, 'push');
            app.CalculateN2OButton.Enable = 'off';
            app.CalculateN2OButton.Visible = 'off';
            app.CalculateN2OButton.Text = 'Calculate N2O';
            app.CalculateCH4Button = uibutton(app.EvaluationTab, 'push');
            app.CalculateCH4Button.Enable = 'off';
            app.CalculateCH4Button.Visible = 'off';
            app.CalculateCH4Button.Text = 'Calculate CH4';
            app.StatisticsPanel = uipanel(app.EvaluationTab);
            app.StatisticsPanel.Title = 'Statistics';
            app.StatisticsButtonGroup = uibuttongroup(app.StatisticsPanel);
            app.StatisticsButtonGroup.Title = 'Statistics';
            app.AverageDailyDriving = uiradiobutton(app.StatisticsButtonGroup);
            app.AverageDailyDriving.Text = 'Average Daily Driving';
            app.AverageDailyDriving.Value = true;
            app.ProbabilityDistribution = uiradiobutton(app.StatisticsButtonGroup);
            app.ProbabilityDistribution.Text = 'Probability Distribution';
            app.Boxplot = uiradiobutton(app.StatisticsButtonGroup);
            app.Boxplot.Text = 'Boxplot';
            app.BinsEditField_2Label = uilabel(app.StatisticsButtonGroup);
            app.BinsEditField_2Label.Text = 'Bins';
            app.Bins2 = uieditfield(app.StatisticsButtonGroup, 'numeric');
            app.Bins2.Value = 11;
            app.daysSelection_2 = uibuttongroup(app.StatisticsPanel);
            app.daysSelection_2.Title = 'Day';
            app.weekdays_2 = uiradiobutton(app.daysSelection_2);
            app.weekdays_2.Text = 'Weekdays';
            app.weekdays_2.Value = true;
            app.weekends_2 = uiradiobutton(app.daysSelection_2);
            app.weekends_2.Text = 'Weekends';
            app.week_2 = uiradiobutton(app.daysSelection_2);
            app.week_2.Text = 'Week';
            app.VehicleListBoxLabel_2 = uilabel(app.StatisticsPanel);
            app.VehicleListBoxLabel_2.Text = 'Vehicle';
            app.vehicleSelectionGHG = uilistbox(app.StatisticsPanel);
            app.vehicleSelectionGHG.Items = {};
            app.vehicleSelectionGHG.Multiselect = 'on';
            app.vehicleSelectionGHG.Value = {};
            app.Plot1_GHG = uibutton(app.StatisticsPanel, 'push');
            app.Plot1_GHG.ButtonPushedFcn = createCallbackFcn(app, @Plot1_Evaluation, true);
            app.Plot1_GHG.Text = 'Plot';
            app.UIAxesEval = uiaxes(app.StatisticsPanel);
            title(app.UIAxesEval, 'Travels')
            xlabel(app.UIAxesEval, '')
            ylabel(app.UIAxesEval, 'Distance (Km)')
            app.GreenHouseGasEmissionsCO2CH2N2OHFCCO2ePanel = uipanel(app.EvaluationTab);
            app.GreenHouseGasEmissionsCO2CH2N2OHFCCO2ePanel.Title = 'Green House Gas Emissions (CO2, CH2, N2O, HFC) CO2e';
            app.ConsumptionListBoxLabel = uilabel(app.GreenHouseGasEmissionsCO2CH2N2OHFCCO2ePanel);
            app.ConsumptionListBoxLabel.Text = 'Consumption';
            app.Consumption = uilistbox(app.GreenHouseGasEmissionsCO2CH2N2OHFCCO2ePanel);
            app.Consumption.Items = {'City', 'Highway', 'Combined'};
            app.Consumption.Value = 'City';
            app.ICEListBoxLabel = uilabel(app.GreenHouseGasEmissionsCO2CH2N2OHFCCO2ePanel);
            app.ICEListBoxLabel.Text = 'ICE';
            app.ICEListBox = uilistbox(app.GreenHouseGasEmissionsCO2CH2N2OHFCCO2ePanel);
            app.ICEListBox.Items = {'IC1', 'IC2', 'IC3', 'IC4', 'IC5', 'IC6', 'IC7', 'IC8', 'IC9', 'IC10', 'IC11', 'IC12', 'IC13', 'IC14', 'IC15', 'IC16', 'IC17', 'IC18', 'IC19', 'IC20'};
            app.ICEListBox.Multiselect = 'on';
            app.ICEListBox.Value = {'IC1'};
            app.CalculateGHGButton = uibutton(app.GreenHouseGasEmissionsCO2CH2N2OHFCCO2ePanel, 'push');
            app.CalculateGHGButton.ButtonPushedFcn = createCallbackFcn(app, @calculateGHG, true);
            app.CalculateGHGButton.Text = 'Calculate GHG';
            app.EVListBoxLabel = uilabel(app.GreenHouseGasEmissionsCO2CH2N2OHFCCO2ePanel);
            app.EVListBoxLabel.Text = 'EV';
            app.EVListBox = uilistbox(app.GreenHouseGasEmissionsCO2CH2N2OHFCCO2ePanel);
            app.EVListBox.Items = {'EV1', 'EV2', 'EV3', 'EV4'};
            app.EVListBox.Multiselect = 'on';
            app.EVListBox.Value = {'EV1'};
            app.UIAxesGHG = uiaxes(app.GreenHouseGasEmissionsCO2CH2N2OHFCCO2ePanel);
            title(app.UIAxesGHG, 'GHG Emissions')
            xlabel(app.UIAxesGHG, '')
            ylabel(app.UIAxesGHG, 'Metric tonnes')
            app.FuelButtonGroup = uibuttongroup(app.GreenHouseGasEmissionsCO2CH2N2OHFCCO2ePanel);
            app.FuelButtonGroup.Title = 'Fuel';
            app.PetrolButton = uiradiobutton(app.FuelButtonGroup);
            app.PetrolButton.Text = 'Petrol';
            app.PetrolButton.Value = true;
            app.DieselButton = uiradiobutton(app.FuelButtonGroup);
            app.DieselButton.Text = 'Diesel';
            app.E10Button = uiradiobutton(app.FuelButtonGroup);
            app.E10Button.Text = 'E10';
            app.ElectricityButtonGroup = uibuttongroup(app.GreenHouseGasEmissionsCO2CH2N2OHFCCO2ePanel);
            app.ElectricityButtonGroup.Title = 'E-Provider';
            app.TurkuEnButton = uiradiobutton(app.ElectricityButtonGroup);
            app.TurkuEnButton.Text = 'TurkuEn';
            app.TurkuEnButton.Value = true;
            app.HelenButton = uiradiobutton(app.ElectricityButtonGroup);
            app.HelenButton.Text = 'Helen';
            app.RoutePlannerTab = uitab(app.TabGroup);
            app.RoutePlannerTab.Title = 'Route Planner';
            app.createaNewTrip = uibutton(app.RoutePlannerTab, 'push');
            app.createaNewTrip.ButtonPushedFcn = createCallbackFcn(app, @createaNewTripPushed, true);
            app.createaNewTrip.Text = 'Create a new trip';
            app.loadaRouteButton = uibutton(app.RoutePlannerTab, 'push');
            app.loadaRouteButton.ButtonPushedFcn = createCallbackFcn(app, @loadaRouteButtonPushed, true);
            app.loadaRouteButton.Text = 'Load a route';
            app.DaysListBoxLabel = uilabel(app.RoutePlannerTab);
            app.DaysListBoxLabel.Text = 'Days';
            app.RouteListBox = uilistbox(app.RoutePlannerTab);
            app.plotRoute = uibutton(app.RoutePlannerTab, 'push');
            app.plotRoute.ButtonPushedFcn = createCallbackFcn(app, @plotRoutePushed, true);
            app.plotRoute.Text = 'Plot selected route';
            app.UITable = uitable(app.RoutePlannerTab);
            app.UITable.ColumnName = {'Date'; 'Map'; 'Layer'; 'Stops'; 'Distance (Km)'; 'Consumption (kWh)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ExportCSVButton = uibutton(app.RoutePlannerTab, 'push');
            app.ExportCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVButtonPushed, true);
            app.ExportCSVButton.Text = 'Export .csv';
            app.RoutePanel = uipanel(app.RoutePlannerTab);
            app.RoutePanel.Title = 'Route';
            app.SatellitepictureCheckBox = uicheckbox(app.RoutePlannerTab);
            app.SatellitepictureCheckBox.ValueChangedFcn = createCallbackFcn(app, @SatellitepictureCheckBoxValueChanged, true);
            app.SatellitepictureCheckBox.Text = 'Satellite picture';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.CO2EmissionsgrCO2LgrCO2kWhPanel = uipanel(app.SettingsTab);
            app.CO2EmissionsgrCO2LgrCO2kWhPanel.Title = 'CO2 Emissions [gr CO2/L]  &  [gr CO2/kWh]';
            app.PetrolEditFieldLabel = uilabel(app.CO2EmissionsgrCO2LgrCO2kWhPanel);
            app.PetrolEditFieldLabel.Text = 'Petrol';
            app.PetrolEditField = uieditfield(app.CO2EmissionsgrCO2LgrCO2kWhPanel, 'numeric');
            app.PetrolEditField.Value = 2290;
            app.DieselEditFieldLabel = uilabel(app.CO2EmissionsgrCO2LgrCO2kWhPanel);
            app.DieselEditFieldLabel.Text = 'Diesel';
            app.DieselEditField = uieditfield(app.CO2EmissionsgrCO2LgrCO2kWhPanel, 'numeric');
            app.DieselEditField.Value = 2660;
            app.HelenEditFieldLabel = uilabel(app.CO2EmissionsgrCO2LgrCO2kWhPanel);
            app.HelenEditFieldLabel.Text = 'Helen';
            app.HelenEditField = uieditfield(app.CO2EmissionsgrCO2LgrCO2kWhPanel, 'numeric');
            app.HelenEditField.Value = 191;
            app.TurkuEnergiaEditFieldLabel = uilabel(app.CO2EmissionsgrCO2LgrCO2kWhPanel);
            app.TurkuEnergiaEditFieldLabel.Text = 'Turku Energia';
            app.TurkuEnergiaEditField = uieditfield(app.CO2EmissionsgrCO2LgrCO2kWhPanel, 'numeric');
            app.TurkuEnergiaEditField.Value = 215;
            app.COemissionstoproduce1kWhofelectricityLabel = uilabel(app.CO2EmissionsgrCO2LgrCO2kWhPanel);
            app.COemissionstoproduce1kWhofelectricityLabel.Text = 'CO@ emissions to produce 1 kWh of electricity';
            app.E10EditFieldLabel = uilabel(app.CO2EmissionsgrCO2LgrCO2kWhPanel);
            app.E10EditFieldLabel.Text = 'E10';
            app.E10EditField = uieditfield(app.CO2EmissionsgrCO2LgrCO2kWhPanel, 'numeric');
            app.E10EditField.Value = 2210;
            app.GlobalWarmingPotentialCO2ePanel = uipanel(app.SettingsTab);
            app.GlobalWarmingPotentialCO2ePanel.Title = 'Global Warming Potential [CO2e]';
            app.MethaneEditFieldLabel = uilabel(app.GlobalWarmingPotentialCO2ePanel);
            app.MethaneEditFieldLabel.Text = 'Methane';
            app.MethaneEditField = uieditfield(app.GlobalWarmingPotentialCO2ePanel, 'numeric');
            app.MethaneEditField.Value = 25;
            app.NitrousOxideEditFieldLabel = uilabel(app.GlobalWarmingPotentialCO2ePanel);
            app.NitrousOxideEditFieldLabel.Text = 'Nitrous Oxide';
            app.NitrousOxideEditField = uieditfield(app.GlobalWarmingPotentialCO2ePanel, 'numeric');
            app.NitrousOxideEditField.Value = 298;
            app.ACEditFieldLabel = uilabel(app.GlobalWarmingPotentialCO2ePanel);
            app.ACEditFieldLabel.Text = 'A/C';
            app.ACEditField = uieditfield(app.GlobalWarmingPotentialCO2ePanel, 'numeric');
            app.ACEditField.Value = 1430;
            app.ICEFuelConsumptionliters100KmPanel = uipanel(app.SettingsTab);
            app.ICEFuelConsumptionliters100KmPanel.Title = 'ICE. Fuel Consumption (liters/100Km)';
            app.CityEditFieldLabel = uilabel(app.ICEFuelConsumptionliters100KmPanel);
            app.CityEditFieldLabel.Text = 'City';
            app.cityICE = uieditfield(app.ICEFuelConsumptionliters100KmPanel, 'numeric');
            app.cityICE.Value = 7.3;
            app.HighwayEditFieldLabel = uilabel(app.ICEFuelConsumptionliters100KmPanel);
            app.HighwayEditFieldLabel.Text = 'Highway';
            app.highwayICE = uieditfield(app.ICEFuelConsumptionliters100KmPanel, 'numeric');
            app.highwayICE.Value = 4.6;
            app.CombinedEditFieldLabel = uilabel(app.ICEFuelConsumptionliters100KmPanel);
            app.CombinedEditFieldLabel.Text = 'Combined';
            app.combinedICE = uieditfield(app.ICEFuelConsumptionliters100KmPanel, 'numeric');
            app.combinedICE.Value = 5.6;
            app.DrivingbehaviourPanel = uipanel(app.SettingsTab);
            app.DrivingbehaviourPanel.Title = 'Driving behaviour';
            app.WeekdayEditFieldLabel = uilabel(app.DrivingbehaviourPanel);
            app.WeekdayEditFieldLabel.Text = 'Weekday';
            app.WeekdayEditField = uieditfield(app.DrivingbehaviourPanel, 'numeric');
            app.WeekdayEditField.Value = 4.8;
            app.WeekendEditFieldLabel = uilabel(app.DrivingbehaviourPanel);
            app.WeekendEditFieldLabel.Text = 'Weekend';
            app.WeekendEditField = uieditfield(app.DrivingbehaviourPanel, 'numeric');
            app.WeekendEditField.Value = 4.6;
            app.HolidaysEditFieldLabel = uilabel(app.DrivingbehaviourPanel);
            app.HolidaysEditFieldLabel.Text = 'Holidays';
            app.HolidaysEditField = uieditfield(app.DrivingbehaviourPanel, 'numeric');
            app.HolidaysEditField.Value = 5.6;
            app.CombinedEditField_4Label = uilabel(app.DrivingbehaviourPanel);
            app.CombinedEditField_4Label.Text = 'Combined';
            app.CombinedEditField_4 = uieditfield(app.DrivingbehaviourPanel, 'numeric');
            app.CombinedEditField_4.Value = 16.5;
            app.EVEnergyConsumptionkWh100KmPanel = uipanel(app.SettingsTab);
            app.EVEnergyConsumptionkWh100KmPanel.Title = 'EV. Energy Consumption (kWh/100Km)';
            app.CityEditField_2Label = uilabel(app.EVEnergyConsumptionkWh100KmPanel);
            app.CityEditField_2Label.Text = 'City';
            app.cityEV = uieditfield(app.EVEnergyConsumptionkWh100KmPanel, 'numeric');
            app.cityEV.Value = 19.5;
            app.HighwayEditField_2Label = uilabel(app.EVEnergyConsumptionkWh100KmPanel);
            app.HighwayEditField_2Label.Text = 'Highway';
            app.highwayEV = uieditfield(app.EVEnergyConsumptionkWh100KmPanel, 'numeric');
            app.highwayEV.Value = 29.2;
            app.CombinedEditField_5Label = uilabel(app.EVEnergyConsumptionkWh100KmPanel);
            app.CombinedEditField_5Label.Text = 'Combined';
            app.combinedEV = uieditfield(app.EVEnergyConsumptionkWh100KmPanel, 'numeric');
            app.combinedEV.Value = 23.8;
            app.terminal = uieditfield(app.UIFigure, 'text');
            app.terminalLabel = uilabel(app.UIFigure);
            app.terminalLabel.Text = 'Terminal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoutePlannerV0_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end